classdef panorama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SaveImageButton            matlab.ui.control.Button
        SIFTParametersLabel        matlab.ui.control.Label
        SigmaSpinner               matlab.ui.control.Spinner
        SigmaSpinnerLabel          matlab.ui.control.Label
        LayersPerOctaveSpinner     matlab.ui.control.Spinner
        LayersPerOctaveLabel       matlab.ui.control.Label
        ContrastThresholdSpinner   matlab.ui.control.Spinner
        ContrastThresholdLabel     matlab.ui.control.Label
        EdgeThresholdSpinner       matlab.ui.control.Spinner
        EdgeThresholdSpinnerLabel  matlab.ui.control.Label
        DoneByAbdelRahmanAlsabbaghSubhiAbdallaandAhmadAlMunayyerLabel  matlab.ui.control.Label
        ComputerVisionProjectPanoramaStitchingLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        StitchImagesButton         matlab.ui.control.Button
        UploadImagesButton         matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         fileNames;
         folderPath;    
 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: UploadImagesButton
        function UploadImagesButtonPushed(app, event)
            % Allow user to select multiple images
               [app.fileNames, app.folderPath] = uigetfile({'*.jpg;*.png;*.bmp;*.tif;*.tiff', 'Image files'}, 'Select Images', 'MultiSelect', 'on');
                
                % Verify that images are selected
                if isequal(app.fileNames, 0)
                    uialert(app, 'Please select valid images.', 'Invalid Selection', 'Icon', 'error');
                    return;
                end
                % Construct full file paths
                selectedFilePaths = fullfile(app.folderPath, app.fileNames);
                
                % Create an imageDatastore for the selected images
                    fullScene = imageDatastore(selectedFilePaths);
                     montage(fullScene.Files, 'Parent', app.UIAxes);
               
                    
        end

        % Callback function
        function ImageDataStoreButtonPushed(app, event)
            % Create an imageDatastore for the local directory.
         %   full_scene = imageDatastore(local_dir);
        end

        % Button pushed function: StitchImagesButton
        function StitchImagesButtonPushed(app, event)
        % Construct full file paths
                selected_file_paths = fullfile(app.folderPath, app.fileNames);
                
                % Create an imageDatastore for the selected images
                    image_datastore = imageDatastore(selected_file_paths);
        
                 
              % Check if images are loaded
                if isempty(image_datastore) || numel(image_datastore.Files) < 2
                    uialert(app.UIFigure, 'Please load at least two images for stitching.', 'Image Loading Error');
                    return;
                end
            
            % Generate full file paths for selected images
             
            
            % Check if images are loaded
            if isempty(image_datastore) || numel(image_datastore.Files) < 2
                uialert(app.UIFigure, 'Please load at least two images for stitching.', 'Image Loading Error');

                return;
            end
            contrast_threshold = app.ContrastThresholdSpinner.Value;
            edge_threshold = app.EdgeThresholdSpinner.Value;
            lpo  =  app.LayersPerOctaveSpinner.Value;
            sigma = app.SigmaSpinner.Value;

            % Read the first image from the image set.
            first_image = readimage(image_datastore, 1);
            
            % Initialize features for the first image
            gray_first_image = im2gray(first_image);
            points_first_image = detectSIFTFeatures(gray_first_image, 'ContrastThreshold', contrast_threshold, 'EdgeThreshold', edge_threshold, 'NumLayersInOctave', lpo, 'Sigma', sigma);
            [features_first_image, points_first_image] = extractFeatures(gray_first_image, points_first_image);
            
            % Initialize all the transformations to the identity matrix.
            num_images = numel(image_datastore.Files);
            transformations(num_images) = projtform2d;
            
            % Initialize variable to hold image sizes.
            image_sizes = zeros(num_images, 2);
            
            % Iterate over remaining image pairs
            for n = 2:num_images
                % Store points and features for the previous image.
                points_previous = points_first_image;
                features_previous = features_first_image;
            
                % Read the current image.
                current_image = readimage(image_datastore, n);
            
                % Convert the current image to grayscale.
                gray_current_image = im2gray(current_image);
            
                % Save image size.
                image_sizes(n, :) = size(gray_current_image);
            
                % Detect and extract SIFT features for the current image.
                points_first_image = detectSIFTFeatures(gray_current_image, 'ContrastThreshold', contrast_threshold, 'EdgeThreshold', edge_threshold, 'NumLayersInOctave', lpo, 'Sigma', sigma);
                [features_first_image, points_first_image] = extractFeatures(gray_current_image, points_first_image);
            
                % Find correspondences between the current and previous images.
                index_pairs = matchFeatures(features_first_image, features_previous, 'Unique', true);
            
                matched_points = points_first_image(index_pairs(:, 1), :);
                matched_points_prev = points_previous(index_pairs(:, 2), :);
            
                % Estimate the transformation between the current and previous images.
                transformations(n) = estgeotform2d(matched_points, matched_points_prev, 'projective', 'Confidence', 99.9, 'MaxNumTrials', 2000);
            
                % Compute T(1) * T(2) * ... * T(n-1) * T(n).
                transformations(n).A = transformations(n-1).A * transformations(n).A;
            end
            
            % Compute the output limits for each transformation.
            for i = 1:numel(transformations)
                [x_limits(i, :), y_limits(i, :)] = outputLimits(transformations(i), [1 image_sizes(i, 2)], [1 image_sizes(i, 1)]);
            end
            
            avg_x_limit = mean(x_limits, 2);
            [~, idx] = sort(avg_x_limit);
            center_idx = floor((numel(transformations)+1)/2);
            center_image_idx = idx(center_idx);
            
            t_inv = invert(transformations(center_image_idx));
            for i = 1:numel(transformations)
                transformations(i).A = t_inv.A * transformations(i).A;
            end
            
            for i = 1:numel(transformations)
                [x_limits(i, :), y_limits(i, :)] = outputLimits(transformations(i), [1 image_sizes(i, 2)], [1 image_sizes(i, 1)]);
            end
            
            max_image_size = max(image_sizes);
            
            % Find the minimum and maximum output limits.
            x_min = min([1; x_limits(:)]);
            x_max = max([max_image_size(2); x_limits(:)]);
            
            y_min = min([1; y_limits(:)]);
            y_max = max([max_image_size(1); y_limits(:)]);
            
            % Width and height of the panorama.
            width  = round(x_max - x_min);
            height = round(y_max - y_min);
            
            % Initialize the "empty" panorama.
            result_panorama = zeros([height width 3], 'like', first_image);
            
            blender = vision.AlphaBlender('Operation', 'Binary mask', 'MaskSource', 'Input port');
            
            % Create a 2-D spatial reference object defining the size of the panorama.
            x_limits_panorama = [x_min x_max];
            y_limits_panorama = [y_min y_max];
            panorama_view = imref2d([height width], x_limits_panorama, y_limits_panorama);
            
            % Create the panorama.
            for i = 1:num_images
                current_image = readimage(image_datastore, i);
            
                % Transform the current image into the panorama.
                warped_image = imwarp(current_image, transformations(i), 'OutputView', panorama_view);
            
                % Generate a binary mask.
                mask = imwarp(true(size(current_image, 1), size(current_image, 2)), transformations(i), 'OutputView', panorama_view);
            
                % Overlay the warped_image onto the panorama.
                result_panorama = step(blender, result_panorama, warped_image, mask);
            end


    imshow(result_panorama, 'Parent',app.UIAxes2);
   
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
             % Get the image data from the UIAxes
            imageData = frame2im(getframe(app.UIAxes2));

            % Ask the user for the file name and location
            [fileName, filePath] = uiputfile({'*.png', 'PNG files (*.png)'; '*.jpg', 'JPEG files (*.jpg)'}, 'Save Image As');

            % Check if the user canceled the operation
            if fileName == 0
                return;
            end

            % Combine the file name and path
            fullFilePath = fullfile(filePath, fileName);

            % Save the image
            imwrite(imageData, fullFilePath);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Uploaded Images')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Stitched Panorama')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTickLabel = '';
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImagesButtonPushed, true);
            app.UploadImagesButton.Text = 'Upload Images';
            app.StitchImagesButton = uibutton(app.UIFigure, 'push');
            app.StitchImagesButton.ButtonPushedFcn = createCallbackFcn(app, @StitchImagesButtonPushed, true);
            app.StitchImagesButton.Text = 'Stitch Images';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ComputerVisionProjectPanoramaStitchingLabel = uilabel(app.UIFigure);
            app.ComputerVisionProjectPanoramaStitchingLabel.Text = 'Computer Vision Project: Panorama Stitching';
            app.DoneByAbdelRahmanAlsabbaghSubhiAbdallaandAhmadAlMunayyerLabel = uilabel(app.UIFigure);
            app.DoneByAbdelRahmanAlsabbaghSubhiAbdallaandAhmadAlMunayyerLabel.Text = 'Done By: Abdel Rahman Alsabbagh, Subhi Abdalla, and Ahmad Al Munayyer';
            app.EdgeThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.EdgeThresholdSpinnerLabel.Text = {'Edge'; 'Threshold'};
            app.EdgeThresholdSpinner = uispinner(app.UIFigure);
            app.EdgeThresholdSpinner.Step = 0.01;
            app.EdgeThresholdSpinner.Limits = [1 Inf];
            app.EdgeThresholdSpinner.Value = 10;
            app.ContrastThresholdLabel = uilabel(app.UIFigure);
            app.ContrastThresholdLabel.Text = {'Contrast'; 'Threshold'};
            app.ContrastThresholdSpinner = uispinner(app.UIFigure);
            app.ContrastThresholdSpinner.Step = 0.0001;
            app.ContrastThresholdSpinner.Limits = [0 1];
            app.ContrastThresholdSpinner.Value = 0.0133;
            app.LayersPerOctaveLabel = uilabel(app.UIFigure);
            app.LayersPerOctaveLabel.Text = {'Layers'; ' Per Octave'};
            app.LayersPerOctaveSpinner = uispinner(app.UIFigure);
            app.LayersPerOctaveSpinner.Limits = [1 Inf];
            app.LayersPerOctaveSpinner.Value = 3;
            app.SigmaSpinnerLabel = uilabel(app.UIFigure);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.UIFigure);
            app.SigmaSpinner.Step = 0.01;
            app.SigmaSpinner.Limits = [1 2];
            app.SigmaSpinner.Value = 1.6;
            app.SIFTParametersLabel = uilabel(app.UIFigure);
            app.SIFTParametersLabel.Text = 'SIFT Parameters';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = panorama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end